classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SelectimageButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectimageButton
        function SelectimageButtonPushed(app, event)
            [filename, pathname] = uigetfile( ...
                {'*.png;', 'PNG image (*.png)';
                 '*.jpg',  'JPEG image (*.jpg)'; ...
                 '*.tif',  'TIF image (*.tif)' ...
                }, 'Choose an image');
            full_filename = fullfile(pathname, filename);
            [A, delimiterOut] = importdata(full_filename);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectimageButton = uibutton(app.UIFigure, 'push');
            app.SelectimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectimageButtonPushed, true);
            app.SelectimageButton.Text = 'Select image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end